classdef pokerGameUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ReadyButton         matlab.ui.control.StateButton
        ShotButton          matlab.ui.control.Button
        PassButton          matlab.ui.control.Button
        CardNum_player_1    matlab.ui.control.Label
        backCard_player_1   matlab.ui.control.Image
        CardNum_player_2    matlab.ui.control.Label
        backCard_player_2   matlab.ui.control.Image
        avatar_player_2     matlab.ui.control.Image
        avatar_currplayer   matlab.ui.control.Image
        avatar_player_1     matlab.ui.control.Image
        CardNum_currplayer  matlab.ui.control.Label
    end

    
    properties (Access = public)
        % Three players
         currPlayer 
         player_1
         player_2
         gameEngine
         currDispCards = {}; % Only used to display other players' shoted cards
    end
    
    methods (Access = private)
        
        function selectCard(app, src, event, indx)
            if (app.currPlayer.cards_img{1, indx}.Position(1,2) == 22)
                app.currPlayer.cards_img{1, indx}.Position(1,2) = 62;
                app.currPlayer.cards_img{2, indx} = true;
                app.currPlayer.selectNum = app.currPlayer.selectNum + 1;
            elseif (app.currPlayer.cards_img{1, indx}.Position(1,2) == 62)
                app.currPlayer.cards_img{1, indx}.Position(1,2) = 22;
                app.currPlayer.cards_img{2, indx} = false;
                app.currPlayer.selectNum = app.currPlayer.selectNum - 1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ReadyButton
        function ReadyButtonValueChanged(app, event)
            value = app.ReadyButton.Value;
            app.currPlayer.isActive = true;
            app.ReadyButton.Visible = false;
            % update 'ready' in UIs
            app.CardNum_currplayer.Text = 'Ready';
            app.gameEngine.update;
            drawnow
            
            if (app.gameEngine.isDistribute == false)
                app.gameEngine.assignRole;
                app.gameEngine.distributeCards;
                app.gameEngine.isDistribute = true;
            end
            
            % Create curr player cards components and set them as invisible
            for i = 1:app.currPlayer.cardNum
                % app.currPlayer.cards_img: 
                app.currPlayer.cards_img{1, i} = uiimage(app.UIFigure,'ImageSource', app.currPlayer.cards{3, i});
                app.currPlayer.cards_img{2, i} = false; % is seleted
                app.currPlayer.cards_img{3, i} = false; % is shoted
                app.currPlayer.cards_img{1, i}.ImageClickedFcn = {@app.selectCard, i};
                app.currPlayer.cards_img{1, i}.Visible = false;
            end
            
            if (app.player_1.isActive == true && app.player_2.isActive == true)
                app.gameEngine.isStart = true;
                app.gameEngine.startGame;
            end
            
            if (app.gameEngine.isStart == true)
                app.gameEngine.update;
            end
        end

        % Callback function
        function ButtonValueChanged(app, event)

        end

        % Button pushed function: ShotButton
        function ShotButtonPushed(app, event)
            mid = (1 + app.currPlayer.selectNum)/2;
            if (app.currPlayer.role == 0)
                up = 20;
            elseif (app.currPlayer.role == 1)
                up = 17;
            end
            indx_selected = 1;
            for i = 1: up
                if (app.currPlayer.cards_img{2, i} == true)
                    x = 565 + (indx_selected - mid) * 32;
                    app.currPlayer.cards_img{1, i}.Position = [x, 446, 173, 256];
                    app.currPlayer.cards_img{3, i} = true;
                    % app.gameEngine.cards_selected = {app.gameEngine.cards_selected, app.currPlayer.cards_img{1, i}};
                    indx_selected = indx_selected + 1;
                end
            end
            %
            app.currPlayer.cardNum = app.currPlayer.cardNum - app.currPlayer.selectNum;
            app.currPlayer.selectNum = 0;
            app.gameEngine.update;
            app.gameEngine.nextTurn;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ReadyButton = uibutton(app.UIFigure, 'state');
            app.ReadyButton.ValueChangedFcn = createCallbackFcn(app, @ReadyButtonValueChanged, true);
            app.ReadyButton.Text = 'Ready';
            app.ShotButton = uibutton(app.UIFigure, 'push');
            app.ShotButton.ButtonPushedFcn = createCallbackFcn(app, @ShotButtonPushed, true);
            app.ShotButton.Icon = 'shot.png';
            app.ShotButton.Text = 'Shot';
            app.PassButton = uibutton(app.UIFigure, 'push');
            app.PassButton.Icon = 'pass.png';
            app.PassButton.Text = 'Pass';
            app.CardNum_player_1 = uilabel(app.UIFigure);
            app.CardNum_player_1.Text = 'Not ready!';
            app.backCard_player_1 = uiimage(app.UIFigure);
            app.backCard_player_1.ImageSource = 'poker_back.jpg';
            app.CardNum_player_2 = uilabel(app.UIFigure);
            app.CardNum_player_2.Text = 'Not ready!';
            app.backCard_player_2 = uiimage(app.UIFigure);
            app.backCard_player_2.ImageSource = 'poker_back.jpg';
            app.avatar_player_2 = uiimage(app.UIFigure);
            app.avatar_player_2.ImageSource = 'icon_default.png';
            app.avatar_currplayer = uiimage(app.UIFigure);
            app.avatar_currplayer.ImageSource = 'icon_default.png';
            app.avatar_player_1 = uiimage(app.UIFigure);
            app.avatar_player_1.ImageSource = 'icon_default.png';
            app.CardNum_currplayer = uilabel(app.UIFigure);
            app.CardNum_currplayer.Text = 'Not ready!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pokerGameUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end